classdef Dobot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        q1SliderLabel        matlab.ui.control.Label
        q1Slider             matlab.ui.control.Slider
        q2SliderLabel        matlab.ui.control.Label
        q2Slider             matlab.ui.control.Slider
        q3SliderLabel        matlab.ui.control.Label
        q3Slider             matlab.ui.control.Slider
        StartMainButton      matlab.ui.control.Button
        XEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        ZEditField           matlab.ui.control.NumericEditField
        InverseButton        matlab.ui.control.Button
        estopButton          matlab.ui.control.StateButton
        ForwardButton        matlab.ui.control.Button
        InitialiseButton     matlab.ui.control.Button
        SwitchLabel          matlab.ui.control.Label
        Switch               matlab.ui.control.ToggleSwitch
        LoadModelsButton     matlab.ui.control.Button
        q4SliderLabel        matlab.ui.control.Label
        q4Slider             matlab.ui.control.Slider
        CowInvasionButton    matlab.ui.control.Button
        SafetySymbolButton   matlab.ui.control.Button
        WorldsCollideButton  matlab.ui.control.Button
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Common variable to be accessed by all functions
    properties (Access = public)
        Property % Description
        dobot = Dobot().model;
        %bot = OtherDobotFunctions(); %comment out if not using real robot
        steps = 20;
        cupSteps = 3;
        neutralQ = deg2rad([0,0,0,0]);
        tableHeight = 0.74;
        cupLocations = [];
        ballLocations = [];
        suctionOffset = 0.07;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)

            % Move the robot to the neutral position
            currentQ = app.dobot.getpos();
            qMat = jtraj(currentQ,app.neutralQ,app.steps);
            for i = 1:app.steps
                currentTR = app.dobot.fkine(qMat(i,:));
                if currentTR(3,4) < app.tableHeight+app.suctionOffset
                    app.estopButton.Value = true;
                end
                if ~app.estopButton.Value
                   app.dobot.animate(qMat(i,:));  
                   
                end 
            end
            
            %if real robot mode is selected
            if app.Switch.Value == "Real" && ~app.estopButton.Value
                app.bot.initialise
                realQ = realQCalc(app.neutralQ);
                app.bot.setTargetJointState(realQ);
            end
            %update slider values
            app.q1Slider.Value = rad2deg(qMat(i,1));    
            app.q2Slider.Value = rad2deg(qMat(i,2)); 
            app.q3Slider.Value = rad2deg(qMat(i,3)); 
            app.q4Slider.Value = rad2deg(qMat(i,4));
            
            %update XYZ coordinates in textbox
            endTR = app.dobot.fkine(app.neutralQ);
            app.XEditField.Value = endTR(1,4);
            app.YEditField.Value = endTR(2,4);
            app.ZEditField.Value = endTR(3,4)-app.tableHeight;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % Obtain slider values for joint angles
            q = deg2rad([app.q1Slider.Value, app.q2Slider.Value, app.q3Slider.Value, app.q4Slider.Value])
            
            %update the XYZ coordinates for the joint angles in the textboxes
            endTR = app.dobot.fkine(q);
            
            %draw trajectory for the robot if the estop is not applied
            currentQ = app.dobot.getpos();
            qMat = jtraj(currentQ,q,app.steps);
            
            for i = 1:app.steps
                currentTR = app.dobot.fkine(qMat(i,:));
                if currentTR(3,4) < app.tableHeight+app.suctionOffset
                    app.estopButton.Value = true;
                end
                if ~app.estopButton.Value
                    app.dobot.animate(qMat(i,:));
                    app.XEditField.Value = currentTR(1,4);
                    app.YEditField.Value = currentTR(2,4);
                    app.ZEditField.Value = currentTR(3,4)-app.tableHeight;
                end
            end    
                  
            if app.Switch.Value == "Real" && ~app.estopButton.Value
                realQ = realQCalc(qMat(app.steps,:));
                app.bot.setTargetJointState(realQ);
            end
            
            currentQ = app.dobot.getpos();
            app.q1Slider.Value = rad2deg(currentQ(1));
            app.q2Slider.Value = rad2deg(currentQ(2));
            app.q3Slider.Value = rad2deg(currentQ(3));
            app.q4Slider.Value = rad2deg(currentQ(4));
              
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % Gets the X,Y,Z coordinates from the text boxes written in by user
            goalLocation = [app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value+app.tableHeight];
            
            % Gets the Q matrix for the trajectory of the robot
            qMat = GoGoal(app.dobot,goalLocation,app.neutralQ,app.steps);
            
            % Iterates the through the joint configurations and moves if estop is not pressed
            for i = 1:app.steps
                currentTR = app.dobot.fkine(qMat(i,:));
                if currentTR(3,4) < app.tableHeight + app.suctionOffset
                    app.estopButton.Value = true;
                end
                if ~app.estopButton.Value
                   app.dobot.animate(qMat(i,:));                
                end           
            end
            
            if app.Switch.Value == "Real" && ~app.estopButton.Value
                realQ = realQCalc(qMat(app.steps,:));
                app.bot.setTargetJointState(realQ);
            end
            
            %update the slider values to the current joint positions
            currentQ = app.dobot.getpos();
            app.q1Slider.Value = rad2deg(currentQ(1));    
            app.q2Slider.Value = rad2deg(currentQ(2)); 
            app.q3Slider.Value = rad2deg(currentQ(3));
            app.q4Slider.Value = rad2deg(currentQ(4));
            rad2deg(app.dobot.getpos())
            %Displays the actual XYZ coordinates of the end effector in the text box
            actualTR = app.dobot.fkine(currentQ);
            app.XEditField.Value = actualTR(1,4);
            app.YEditField.Value = actualTR(2,4);
            app.ZEditField.Value = actualTR(3,4)-app.tableHeight;
        end

        % Button pushed function: StartMainButton
        function StartMainButtonPushed(app, event)
            hold on;
            %get object locations
            [numCups,Position] = size(app.cupLocations);
            for a = 1:numCups
                currentCup = app.cupLocations(a,:);
                currentBall = app.ballLocations(a,:);
                currentCup(3) = currentCup(3)+0.2; %0.12
                currentBall(3) = currentBall(3)+0.2;

                %go to ball
                qMatBall = GoGoal(app.dobot,currentBall,app.neutralQ,app.steps);
                for i = 1:app.steps
                    currentTR = app.dobot.fkine(qMatBall(i,:));
                    if currentTR(3,4) < app.tableHeight + app.suctionOffset
                        app.estopButton.Value = true;
                    end
                    if ~app.estopButton.Value
                       app.dobot.animate(qMatBall(i,:));                
                    end           
                end
                
                %move real robot
                if app.Switch.Value == "Real" && ~app.estopButton.Value
                    realQ = realQCalc(qMatBall(app.steps,:));
                    app.bot.setTargetJointState(realQ);
                end

                %go down in a straight line to pick up ball
                for i = currentBall(3):-0.01:0.13 + app.tableHeight
                    currentBall(3) = i;
                    
                    qMatDrop = GoGoal(app.dobot,currentBall,qMatBall(app.steps,:),app.cupSteps);
                    for j = 1:app.cupSteps
                        currentTR = app.dobot.fkine(qMatDrop(j,:));
                        if currentTR(3,4) < app.tableHeight + app.suctionOffset
                            app.estopButton.Value = true;
                        end
                        if ~app.estopButton.Value
                           app.dobot.animate(qMatDrop(j,:));                
                        end    
                    end
                    if app.Switch.Value == "Real" && ~app.estopButton.Value
                        realQ = realQCalc(qMatDrop(app.cupSteps,:));
                        app.bot.setTargetJointState(realQ);
                    end

                end
                if app.Switch.Value == "Real" && ~app.estopButton.Value
                    pause(5);
                    app.bot.setToolState(1);
                end
                
                %go back up in a straight line
                for i = currentBall(3):0.01:0.23 + app.tableHeight
                    currentBall(3) = i;
                    
                    qMatRise = GoGoal(app.dobot,currentBall,qMatBall(app.steps,:),app.cupSteps);
                    for j = 1:app.cupSteps
                        currentTR = app.dobot.fkine(qMatRise(j,:));
                        if currentTR(3,4) < app.tableHeight + app.suctionOffset
                            app.estopButton.Value = true;
                        end
                        if ~app.estopButton.Value
                           app.dobot.animate(qMatRise(j,:));                
                        end    
                    end
                    if app.Switch.Value == "Real" && ~app.estopButton.Value
                        realQ = realQCalc(qMatRise(app.cupSteps,:));
                        app.bot.setTargetJointState(realQ);
                    end
                end
            
                %go to neutral position
                qMatNeutral = jtraj(qMatBall(app.steps,:),app.neutralQ,app.steps);
                for i = 1:app.steps
                    currentTR = app.dobot.fkine(qMatNeutral(i,:));
                    if currentTR(3,4) < app.tableHeight + app.suctionOffset
                        app.estopButton.Value = true;
                    end
                    if ~app.estopButton.Value
                       app.dobot.animate(qMatNeutral(i,:));                
                    end           
                end
                if app.Switch.Value == "Real" && ~app.estopButton.Value
                    realQ = realQCalc(qMatNeutral(app.steps,:));
                    app.bot.setTargetJointState(realQ);
                end
                
                %go to cup
                qMatCup = GoGoal(app.dobot,currentCup,app.neutralQ,app.steps);
                for i = 1:app.steps
                    currentTR = app.dobot.fkine(qMatCup(i,:));
                    if currentTR(3,4) < app.tableHeight + app.suctionOffset
                        app.estopButton.Value = true;
                    end
                    if ~app.estopButton.Value
                       app.dobot.animate(qMatCup(i,:));                
                    end           
                end
                if app.Switch.Value == "Real" && ~app.estopButton.Value
                    realQ = realQCalc(qMatCup(app.steps,:));
                    app.bot.setTargetJointState(realQ);
                end 
                upQ = qMatCup(app.steps,:);
                tempLocation = currentCup;

                %Place ball in cup in a straight line
                for i = tempLocation(3):-0.01:0.13 + app.tableHeight
                    tempLocation(3) = i;
                    
                    qMatDrop = GoGoal(app.dobot,tempLocation,upQ,app.cupSteps);
                    %for i = 1:app.steps
                    for j = 1:app.cupSteps
                        currentTR = app.dobot.fkine(qMatDrop(j,:));
                        if currentTR(3,4) < app.tableHeight + app.suctionOffset
                            app.estopButton.Value = true;
                        end
                        if ~app.estopButton.Value
                           app.dobot.animate(qMatDrop(j,:));                
                        end    
                    end
                    if app.Switch.Value == "Real" && ~app.estopButton.Value
                        realQ = realQCalc(qMatDrop(app.cupSteps,:));
                        app.bot.setTargetJointState(realQ);
                    end
                end
                
                if app.Switch.Value == "Real" && ~app.estopButton.Value
                    pause(5);
                    app.bot.setToolState(0);
                end
                
                %go back up in a straight line
                for i = tempLocation(3):0.01:0.23 + app.tableHeight
                    tempLocation(3) = i;
                    
                    qMatRise = GoGoal(app.dobot,tempLocation,upQ,app.cupSteps);
                    %for i = 1:app.steps
                    for j = 1:app.cupSteps
                        if ~app.estopButton.Value
                            currentTR = app.dobot.fkine(qMatRise(j,:));
                            if currentTR(3,4) < app.tableHeight + app.suctionOffset
                                app.estopButton.Value = true;
                            end
                           app.dobot.animate(qMatRise(j,:));                
                        end    
                    end
                    if app.Switch.Value == "Real" && ~app.estopButton.Value
                        realQ = realQCalc(qMatRise(app.cupSteps,:));
                        app.bot.setTargetJointState(realQ);
                    end
                    
                end
                
                %go to neutral position
                qMatNeutral = jtraj(app.dobot.getpos(),app.neutralQ,app.steps);
                for i = 1:app.steps
                    currentTR = app.dobot.fkine(qMatNeutral(i,:));
                    if currentTR(3,4) < app.tableHeight + app.suctionOffset
                        app.estopButton.Value = true;
                    end
                    if ~app.estopButton.Value
                       app.dobot.animate(qMatNeutral(i,:));                
                    end           
                end
                if app.Switch.Value == "Real" && ~app.estopButton.Value
                    realQ = realQCalc(qMatNeutral(app.steps,:));
                    app.bot.setTargetJointState(realQ);
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
        end

        % Button pushed function: LoadModelsButton
        function LoadModelsButtonPushed(app, event)
            %loads the workspace with all the safety equipment
            Workspace();
            
            %move the robot to the centre of the table
            app.dobot.base = transl(0,0,app.tableHeight+0.05);
            app.dobot.plot(app.dobot.getpos());
            
            %This spawns cups and balls. Is supposed to have some sensing but it didnt get finished thus both options are the same
            if app.Switch.Value == "Simulation"
                app.cupLocations = [[0.2, 0.1, app.tableHeight]; [0.2,0-0.1,app.tableHeight]];
                app.ballLocations = [[0.1, -0.2, app.tableHeight]; [0.1,0.2,app.tableHeight]];
            else
                %add sensing for the cups and balls
                app.cupLocations = [[0.2, 0.1, app.tableHeight]; [0.2,0-0.1,app.tableHeight]];
                app.ballLocations = [[0.1, -0.2, app.tableHeight]; [0.1,0.2,app.tableHeight]];
            end
            
            %Add the items to the workspace
            hold on;
            PlaceObject('RedCupV2.ply',app.cupLocations(1,:));
            PlaceObject('RedBall.ply',app.ballLocations(1,:));
            PlaceObject('BlueCupV2.ply',app.cupLocations(2,:));
            PlaceObject('BlueBall.ply',app.ballLocations(2,:));
        end

        % Value changed function: estopButton
        function estopButtonValueChanged(app, event)
            %forces the real dobot from moving
            value = app.estopButton.Value;
            if app.Switch.Value == "Real"
                if value
                    app.bot.eStop();
                else
                    app.bot.setMode(4);
                end
            end
        end

        % Button pushed function: CowInvasionButton
        function CowInvasionButtonPushed(app, event)
            if app.Switch.Value == "Real"
                demoLightBarrier(app.bot,app.estopButton);
            end
        end

        % Button pushed function: SafetySymbolButton
        function SafetySymbolButtonPushed(app, event)
            if app.Switch.Value == "Real"
                demoSafetySymbol(app.bot);
            end
        end

        % Button pushed function: WorldsCollideButton
        function WorldsCollideButtonPushed(app, event)
            if app.Switch.Value == "Simulation"
                demoCollision(app.dobot, app.bot, app.tableHeight, "sim");
            else
                demoCollision(app.dobot, app.bot, app.tableHeight, app.estopButton, "real");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-90.001 90.001];
            app.q1Slider.MajorTicks = [-90.001 -60.001 -30.001 -0.00100000000000477 29.999 59.999 90.001];
            app.q1Slider.MajorTickLabels = {'-90', '-60', '-30', '-0', '30', '60', '90'};
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-0.001 85.001];
            app.q2Slider.MajorTicks = [0 20 40 60 80];
            app.q2Slider.MajorTickLabels = {'0', '20', '40', '60', '80'};
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-10.001 90.001];
            app.q3Slider.MajorTicks = [-10 10 30 50 70 90];
            app.q3Slider.MajorTickLabels = {'-10', '10', '30', '50', '70', '90'};
            app.StartMainButton = uibutton(app.UIFigure, 'push');
            app.StartMainButton.ButtonPushedFcn = createCallbackFcn(app, @StartMainButtonPushed, true);
            app.StartMainButton.Text = 'Start Main';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.estopButton = uibutton(app.UIFigure, 'state');
            app.estopButton.ValueChangedFcn = createCallbackFcn(app, @estopButtonValueChanged, true);
            app.estopButton.Icon = 'estop-md.png';
            app.estopButton.Text = 'estop';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InitialiseButton = uibutton(app.UIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Real', 'Simulation'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Simulation';
            app.LoadModelsButton = uibutton(app.UIFigure, 'push');
            app.LoadModelsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelsButtonPushed, true);
            app.LoadModelsButton.Text = 'Load Models';
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-90.001 90.001];
            app.q4Slider.MajorTicks = [-90.001 -60.001 -30.001 -0.00100000000000477 29.999 59.999 90.001];
            app.q4Slider.MajorTickLabels = {'-90', '-60', '-30', '-0', '30', '60', '90'};
            app.CowInvasionButton = uibutton(app.UIFigure, 'push');
            app.CowInvasionButton.ButtonPushedFcn = createCallbackFcn(app, @CowInvasionButtonPushed, true);
            app.CowInvasionButton.Text = 'Cow Invasion';
            app.SafetySymbolButton = uibutton(app.UIFigure, 'push');
            app.SafetySymbolButton.ButtonPushedFcn = createCallbackFcn(app, @SafetySymbolButtonPushed, true);
            app.SafetySymbolButton.Text = 'Safety Symbol';
            app.WorldsCollideButton = uibutton(app.UIFigure, 'push');
            app.WorldsCollideButton.ButtonPushedFcn = createCallbackFcn(app, @WorldsCollideButtonPushed, true);
            app.WorldsCollideButton.Text = 'Worlds Collide';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dobot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end